classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CurrentlyLoadedSoundTextArea  matlab.ui.control.TextArea
        CurrentlyLoadedSoundTextAreaLabel  matlab.ui.control.Label
        Load_Sound_GuitarButton       matlab.ui.control.Button
        Load_Sound_FluteButton        matlab.ui.control.Button
        TopFrequenciesTextArea        matlab.ui.control.TextArea
        TopFrequenciesTextAreaLabel   matlab.ui.control.Label
        ReproducedSoundButton         matlab.ui.control.Button
        OriginalSoundButton           matlab.ui.control.Button
        Load_Sound_DRUMButton         matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        final_sound
        f_1
        curr = 0
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button pushed function: Load_Sound_DRUMButton
        function Loadpush(app, event)
            [data,fs] = audioread("22-14dbl head tom.wav");
            
            data = sum(data,2);
            spec = my_fft(data');      % computing fft
            spec = spec';

            N = length(spec);
            
             % considering first half of fft, till fs/2 (Nyquist freq.)
            spec = spec(1:N/2 + 1); 

            % frequency axis (from 0 to fs/2 , fs/N step size)
            f = 0:fs/N:fs/2;


            % *find peak 5 frequencies*
            [val,des] = sort(abs(spec) , 'descend');
            % peak frequencies   
            peak_freq = f(des(1:5));    
            
            % peak magnitude
            magnitudes =  val(1:5); 
                
            %duration of sound to be played
            duration = 1; 
               
            % time axis
            t=0:1/fs:duration;

            % sin_wave = peak_magnitude*sin(2pi*(peak_frequency)*t)
            sine_waves = magnitudes.*sin(2*pi*peak_freq'*t); 

            % sound_wave = sum of all sine waves
            sound_wave = sum(sine_waves); 
             
            %volume
            vol = 0.001;   
            
             % for wind instruments 
            env1 = vol*sin(2*pi*(0.5/duration)*t); 

             % for percussion instruments 
            env2 = vol./(1+20.*t.^2);                          
            
            %add the sound to reproduced sound
            app.final_sound = env2.*sound_wave;
            app.f_1 = fs;
            
            %plot reproduced and not reproduced sounds
            plot(app.UIAxes2,t(1:length(data)), data)
            plot(app.UIAxes,t, app.final_sound)
            
            %change the textareas
            pk = peak_freq;
            pk = round(pk);
            app.TopFrequenciesTextArea.Value = mat2str(pk);
            app.curr = 2;
            app.CurrentlyLoadedSoundTextArea.Value = "Drum";
            
            %OWN FFT 
            function [spec] = my_fft(array)                                            
                N = 2^(ceil(log2(length(array))));                                                     
                array = [ array , zeros(1,N-length(array))];                                                                                                            
                total_stages = log2(N);                                                                                                                                   
                array = bitrevorder(array);                                                           
                for m = 1:total_stages                                                              
                    for index = 0: 2^m : N-1                                             
                        for t=0:2^(m-1)-1                                                                                           
                            twiddle_exp = N*t/(2^m);                                            
                            W = exp((-1j)*(2*pi)*twiddle_exp/N);                                      
                            a = array(t+index+1) + array(t+index+1+2^(m-1))*W;                                        
                            b = array(t+index+1) - array(t+index+1+2^(m-1))*W;                                        
                            array(t+index+1) = a;                                                       
                            array(t+index+1+2^(m-1)) = b;                                                 
                        end
                    end                                                               
                end
                spec = array;                                                                       
                end

            %}
        end

        % Button pushed function: OriginalSoundButton
        function original_sound_play(app, event)
            if(app.curr == 1)
                sound(audioread("chord-C.wav"),app.f_1)
            end
            if(app.curr == 2)
                sound(audioread("22-14dbl head tom.wav"),app.f_1)
            end
            if(app.curr == 3)
                sound(audioread("bamboo flute 607hz .wav"),app.f_1)
            end
            
        end

        % Button pushed function: ReproducedSoundButton
        function rep_sound_1(app, event)
            sound(app.final_sound,app.f_1)
        end

        % Value changed function: CurrentlyLoadedSoundTextArea
        function Current_Loaded_Sound(app, event)
            value = app.CurrentlyLoadedSoundTextArea.Value;
            
        end

        % Button pushed function: Load_Sound_FluteButton
        function Load_Flute(app, event)
            [data,fs] = audioread("bamboo flute 607hz .wav");
            
            data = sum(data,2);

            % computing fft
            spec = my_fft(data');      
            spec = spec';

            %output sampling rate
            f_play = 16000;

            N = length(spec);
            
             % considering first half of fft, till fs/2 (Nyquist freq.)
            spec = spec(1:N/2 + 1);

             % frequency axis (from 0 to fs/2 , fs/N step size)
            f = 0:fs/N:fs/2;      
            
            % *find peak 5 frequencies*
            [val,des] = sort(abs(spec) , 'descend');

            % peak frequencies   
            peak_freq = f(des(1:10));    
            
            % peak magnitude
            magnitudes =  val(1:10); 
            
            
            %duration of sound to be played
            duration = 1;      
            
            % time axis
            t=0:1/fs:duration;  

            % sin_wave = peak_magnitude*sin(2pi*(peak_frequency)*t)
            sine_waves = magnitudes.*sin(2*pi*peak_freq'*t); 

            % sound_wave = sum of all sine waves
            sound_wave = sum(sine_waves); 
             
            %volume
            vol = 0.001;   
            
             % for wind instruments 
            env1 = vol*sin(2*pi*(0.5/duration)*t);

            % for percussion instruments  
            env2 = vol./(1+20.*t.^2);                          
            
           
            app.final_sound = env1.*sound_wave;
             
            app.f_1 = fs;
            
            %plot the waveforms
            plot(app.UIAxes2,t(1:44100), data(1:44100))
            plot(app.UIAxes,t, app.final_sound)
           
            %fill textareas in gui
            pk = peak_freq;
            pk = round(pk);
            app.TopFrequenciesTextArea.Value = mat2str(pk) ;
            app.CurrentlyLoadedSoundTextArea.Value = "Bamboo Flute";
            app.curr = 3;
            
            %OWN FFT
            function [spec] = my_fft(array)                                            
                N = 2^(ceil(log2(length(array))));                                                     
                array = [ array , zeros(1,N-length(array))];                                                                                                            
                total_stages = log2(N);                                                                                                                                   
                array = bitrevorder(array);                                                           
                for m = 1:total_stages                                                              
                    for index = 0: 2^m : N-1                                             
                        for t=0:2^(m-1)-1                                                                                           
                            twiddle_exp = N*t/(2^m);                                            
                            W = exp((-1j)*(2*pi)*twiddle_exp/N);                                      
                            a = array(t+index+1) + array(t+index+1+2^(m-1))*W;                                        
                            b = array(t+index+1) - array(t+index+1+2^(m-1))*W;                                        
                            array(t+index+1) = a;                                                       
                            array(t+index+1+2^(m-1)) = b;                                                 
                        end
                    end                                                               
                end
                spec = array;                                                                       
                end


        end

        % Button pushed function: Load_Sound_GuitarButton
        function Load_Guitar(app, event)
           [data,fs] = audioread("chord-C.wav");
            
            data = sum(data,2);

            % computing fft
            spec = my_fft(data');      
            spec = spec'
            N = length(spec)
            
            % considering first half of fft, till fs/2 (Nyquist freq.)
            spec = spec(1:N/2 + 1); 

            % frequency axis (from 0 to fs/2 , fs/N step size)
            f = 0:fs/N:fs/2;       

             % *find peak 5 frequencies*
            [val,des] = sort(abs(spec) , 'descend');
            % peak frequencies   
            peak_freq = f(des(1:10));    
            
            % peak magnitude
            magnitudes =  val(1:10); 
            
            %duration of sound to be played
            duration = 1;     

            % time axis
            t=0:1/fs:duration;  

            % sin_wave = peak_magnitude*sin(2pi*(peak_frequency)*t)
            sine_waves = magnitudes.*sin(2*pi*peak_freq'*t); 

            % sound_wave = sum of all sine waves
            sound_wave = sum(sine_waves); 
             
             %volume
            vol = 0.001;  
            
            % for wind instruments
            env1 = vol*sin(2*pi*(0.5/duration)*t);  

             % for percussion instruments
            env2 = vol./(1+20.*t.^2);                           
            
            
            app.final_sound = env2.*sound_wave
            app.f_1 = fs
            
            %plot the reproduced and original sound
            plot(app.UIAxes2,t(1:length(data)), data)
            plot(app.UIAxes,t, app.final_sound)

            %change the textareas in gui
            pk = peak_freq
            pk = round(pk)
            app.TopFrequenciesTextArea.Value = mat2str(pk)
            app.CurrentlyLoadedSoundTextArea.Value = "Guitar";
            app.curr = 1
            
            %OWN FFT
            function [spec] = my_fft(array)                                            
                N = 2^(ceil(log2(length(array))));                                                     
                array = [ array , zeros(1,N-length(array))];                                                                                                            
                total_stages = log2(N);                                                                                                                                   
                array = bitrevorder(array);                                                           
                for m = 1:total_stages                                                              
                    for index = 0: 2^m : N-1                                             
                        for t=0:2^(m-1)-1                                                                                           
                            twiddle_exp = N*t/(2^m);                                            
                            W = exp((-1j)*(2*pi)*twiddle_exp/N);                                      
                            a = array(t+index+1) + array(t+index+1+2^(m-1))*W;                                        
                            b = array(t+index+1) - array(t+index+1+2^(m-1))*W;                                        
                            array(t+index+1) = a;                                                       
                            array(t+index+1+2^(m-1)) = b;                                                 
                        end
                    end                                                               
                end
                spec = array;                                                                       
                end

             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reproduced Sound Wave')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original Sound Wave')
            xlabel(app.UIAxes2, 'TIme')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.Load_Sound_DRUMButton = uibutton(app.UIFigure, 'push');
            app.Load_Sound_DRUMButton.ButtonPushedFcn = createCallbackFcn(app, @Loadpush, true);
            app.Load_Sound_DRUMButton.Text = 'Load_Sound_DRUM';
            app.OriginalSoundButton = uibutton(app.UIFigure, 'push');
            app.OriginalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @original_sound_play, true);
            app.OriginalSoundButton.Text = 'Original Sound';
            app.ReproducedSoundButton = uibutton(app.UIFigure, 'push');
            app.ReproducedSoundButton.ButtonPushedFcn = createCallbackFcn(app, @rep_sound_1, true);
            app.ReproducedSoundButton.Text = 'Reproduced Sound';
            app.TopFrequenciesTextAreaLabel = uilabel(app.UIFigure);
            app.TopFrequenciesTextAreaLabel.Text = 'Top Frequencies ';
            app.TopFrequenciesTextArea = uitextarea(app.UIFigure);
            app.TopFrequenciesTextArea.Value = {'Value in Hz'};
            app.Load_Sound_FluteButton = uibutton(app.UIFigure, 'push');
            app.Load_Sound_FluteButton.ButtonPushedFcn = createCallbackFcn(app, @Load_Flute, true);
            app.Load_Sound_FluteButton.Text = 'Load_Sound_Flute';
            app.Load_Sound_GuitarButton = uibutton(app.UIFigure, 'push');
            app.Load_Sound_GuitarButton.ButtonPushedFcn = createCallbackFcn(app, @Load_Guitar, true);
            app.Load_Sound_GuitarButton.Text = 'Load_Sound_Guitar';
            app.CurrentlyLoadedSoundTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentlyLoadedSoundTextAreaLabel.Text = 'Currently Loaded Sound';
            app.CurrentlyLoadedSoundTextArea = uitextarea(app.UIFigure);
            app.CurrentlyLoadedSoundTextArea.ValueChangedFcn = createCallbackFcn(app, @Current_Loaded_Sound, true);
            app.CurrentlyLoadedSoundTextArea.Value = {'None (click on button to left to load sound)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end